classdef Interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SealesDisponiblesDropDown       matlab.ui.control.DropDown
        SealesDisponiblesDropDownLabel  matlab.ui.control.Label
        AmplitudeSlider                 matlab.ui.control.Slider
        AmplitudeSliderLabel            matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AmplitudeSlider
        function AmplitudeSliderValueChanged(app, event)
            value = app.AmplitudeSlider.Value;
            plot(app.UIAxes,value*peaks)
            app.UIAxes.YLim = [-1000,1000];
        end

        % Value changed function: SealesDisponiblesDropDown
        function SealesDisponiblesDropDownValueChanged(app, event)
            value = app.SealesDisponiblesDropDown.Value;

            switch (value)

                case "Imagen Digital"
                    a = imread("peppers.png");
                    histogram(app.UIAxes,a);
                case "Contador Binario"
                    hold(app.UIAxes,'on')

                    %subplot(3, 1, 1);
                    A = 1;
                    w = 8*pi;
                    rho = 0.5;
                    t = 0:0.001:1;
                    sq = A*square(w*t+rho);
                    plot(app.UIAxes, sq, "r")

                    %subplot(3, 1, 2);
                    A = 1;
                    w = 4*pi;
                    rho = 0.5;
                    sq = A*square(w*t+rho);
                    plot(app.UIAxes, sq, 'b')

                    %subplot(3, 1, 3);
                    A = 1;
                    w = 2*pi;
                    rho = 0.5;
                    sq = A*square(w*t+rho);
                    plot(app.UIAxes, sq, 'g')
                case "Orbita Terrestre"
                    x = 4:pi/100:pi*7;
                    y = (sin(x)+1)/2;
                    plot(app.UIAxes, y, 'LineWidth', 3)
                case "Señal de Reloj Digital"
                    A = 1;
                    w = 8*pi;
                    rho = 0.5;
                    t = 0:0.001:1;
                    sq = A*square(w*t+rho);
                    plot(app.UIAxes, sq, 'LineWidth', 1);
                case "Medicion de Temperatura"
                    [X,Y] = meshgrid(1:0.5:10,1:20);
                    Z =Y.*sin(X) - X.*cos(Y);
                    surf(app.UIAxes,X,Y,Z);
                case "Sismografo"
                    w1=5;
                    w3=50;
                    t= -0.5:.001:.5;
                    x1=sin(w1*pi*t);
                    x2=.5*sin(w3*pi*t);
                    x11=x1+x2;
                    plot(app.UIAxes,x11,'LineWidth',2);
                case "Corriente Alterna"
                    x = linspace(0,20);
                    y = sin(x);
                    plot(app.UIAxes,y);
                case "Pulso Cardiaco"
                    t= -0.5:.001:.5;
                    w1=5;
                    w4=100;
                    x1=sin(w1*pi*t);
                    x2=sin(w4*pi*t);
                    x12=x1.*x2;
                    plot(app.UIAxes,x12,'LineWidth',2);
                case "Altura de una pelota al Rebota"
                    t = linspace(0,10);
                    x1 = abs(sin(t).*(t-10));
                    plot(app.UIAxes,real(x1));
                case "Crecimiento de infectados en una pandemia"
                    t = linspace(0,10);
                    x1 = exp(t);
                    plot(app.UIAxes,real(x1));
                otherwise
                    disp("c");

            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Señales')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AmplitudeSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.UIFigure);
            app.AmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.SealesDisponiblesDropDownLabel = uilabel(app.UIFigure);
            app.SealesDisponiblesDropDownLabel.Text = 'Señales Disponibles';
            app.SealesDisponiblesDropDown = uidropdown(app.UIFigure);
            app.SealesDisponiblesDropDown.Items = {'Imagen Digital', 'Crecimiento de infectados en una pandemia', 'Contador Binario', 'Orbita Terrestre', 'Señal de Reloj Digital', 'Medicion de Temperatura', 'Sismografo', 'Corriente Alterna', 'Pulso Cardiaco', 'Altura de una pelota al Rebota'};
            app.SealesDisponiblesDropDown.ValueChangedFcn = createCallbackFcn(app, @SealesDisponiblesDropDownValueChanged, true);
            app.SealesDisponiblesDropDown.Value = 'Imagen Digital';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end